classdef viewMRE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MREViewerUIFigure           matlab.ui.Figure
        LoadPARRECDataButton        matlab.ui.control.Button
        magnitudeAxes               matlab.ui.control.UIAxes
        phaseAxes                   matlab.ui.control.UIAxes
        infoTable                   matlab.ui.control.Table
        timeAxes                    matlab.ui.control.UIAxes
        UnwrapandSmoothPhaseButton  matlab.ui.control.Button
        SliceSpinnerLabel           matlab.ui.control.Label
        SliceSpinner                matlab.ui.control.Spinner
        DirectionSpinnerLabel       matlab.ui.control.Label
        DirectionSpinner            matlab.ui.control.Spinner
        VibrationSpinnerLabel       matlab.ui.control.Label
        VibrationSpinner            matlab.ui.control.Spinner
        PhaseSpinnerLabel           matlab.ui.control.Label
        PhaseSpinner                matlab.ui.control.Spinner
        MREScanInfoLabel            matlab.ui.control.Label
        filenameLabel               matlab.ui.control.Label
        filenameEditField           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Data = struct; % Description
        header; % Description
        nSlices; % Description
        nVibs;
        nDirs;
        nPhases;
        
        currentSl;
        currentVib;
        currentDir;
        currentPhase;
        currentX;
        currentY;
        inplaneResolution; % Description
    end
    
    methods (Access = private)
        
        
        function updateTimeAxes(app)
            dat = squeeze(app.Data.phData(app.currentX,app.currentY,...
                app.currentSl,app.currentVib,app.currentDir,:));
            plot(app.timeAxes,dat,'-ok','LineWidth',2)
        end
        
        function updateFigureAxes(app)
            % to initialize images
            aa = squeeze(app.Data.magData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            aa = uint8(aa/max(aa(:))*255);
            overlay = zeros(size(app.Data.magData,1),size(app.Data.magData,2));
            overlay(app.currentX-1:app.currentX+1,app.currentY-1:app.currentY+1) = 1; overlay = imbinarize(overlay);
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.magnitudeAxes);
            
            aa = squeeze(app.Data.phData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            aa = aa - min(aa(:)); aa = uint8(aa/max(aa(:))*255);
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.phaseAxes);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPARRECDataButton
        function LoadPARRECDataButtonPushed(app, event)
            addpath(sprintf('/home/%s/lood_storage/divi/Pub/Software/matlab/toolboxes-amc/parrec/',getenv('USER')));
            
            [filenames,inputDir] = uigetfile('*.rec','Select Reconstructed Data(sets)','MultiSelect','on');
            
            if ~iscell(filenames)
                count = 1;
            else
                count = cellfun(@(x) numel(x), filenames);
            end
            
            for nfile=1:count
                
                disp(['Reconstructing scan ' num2str(nfile) '/' num2str(count)]);
                
                if ~iscell(filenames)
                    filename = filenames;
                else
                    filename = filenames{nfile};
                end
                fName = fullfile(inputDir,filename);
                
                app.filenameEditField.Value = filename;
                               
                % Read in the data and header
                [data, h] = readrec_V4_2(fName);
                app.inplaneResolution = h.pixdim;                         % in mm
                
                app.Data.magData = squeeze(data(:,:,:,:,:,1,:));
                tempPh = squeeze(data(:,:,:,:,:,2,:));;
                tempPh = tempPh - min(tempPh(:)) - pi;
                app.Data.phData = tempPh;
                app.header = h;
                app.nSlices = size(data,3);
                app.nVibs = size(data,4);
                app.nDirs = size(data,5);
                app.nPhases = size(data,7);
                
                app.currentSl = round(app.nSlices/2);
                app.SliceSpinner.Limits = [1,app.nSlices];
                app.SliceSpinner.Value = app.currentSl;
                
                app.currentVib = 1;
                app.VibrationSpinner.Limits = [1,app.nVibs];
                app.VibrationSpinner.Value = app.currentVib;
                
                app.currentDir = 2;
                app.DirectionSpinner.Limits = [1,app.nDirs];
                app.DirectionSpinner.Value = app.currentDir;
                
                app.currentPhase = 1;
                app.PhaseSpinner.Limits = [1,app.nPhases];
                app.PhaseSpinner.Value = app.currentPhase;
                
                app.infoTable.Data = cat(2,app.nSlices,app.nDirs,app.nVibs,app.nPhases);
                
                % initialize images
                app.currentX = round(app.header.nrows/2);
                app.currentX = round(app.header.ncols/2);
                updateFigureAxes(app);
            end
        end

        % Window button down function: MREViewerUIFigure
        function MREViewerUIFigureWindowButtonDown(app, event)
            imSize = size(app.Data.magData,1);
            
            % get axis location in the magnitude axis
            xx = round(app.magnitudeAxes.CurrentPoint);
            % check that point makes sense
            x = xx(3); y = xx(1);
            if x > 0 && x < imSize && y > 0 && y < imSize
                app.currentX = x;
                app.currentY = y;
                updateTimeAxes(app);
                updateFigureAxes(app);
            else    % try to get points from phase axis instead
                % get axis location in the magnitude axis
                xx = round(app.phaseAxes.CurrentPoint);
                % check that point makes sense
                x = xx(3); y = xx(1);
                if x > 0 && x < imSize && y > 0 && y < imSize
                    app.currentX = x;
                    app.currentY = y;
                    updateTimeAxes(app);
                    updateFigureAxes(app);
                end
            end
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentSl = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: DirectionSpinner
        function DirectionSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentDir = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: VibrationSpinner
        function VibrationSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentVib = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: PhaseSpinner
        function PhaseSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentPhase = changingValue;
            updateFigureAxes(app);
        end

        % Button pushed function: UnwrapandSmoothPhaseButton
        function UnwrapandSmoothPhaseButtonPushed(app, event)
            addpath(sprintf('/home/%s/lood_storage/divi/Projects/asap/incoming/parrec/MATLAB_Scripts/tempApp/unwrap/',getenv('USER')));
            % perform unwrapping over all directions/vibrations
            h = waitbar(0, sprintf('Unwrapping and smoothing all directions and vibrations...'));
            count = 0;
            for vib = 1:app.nVibs
                for Dir = 1:app.nDirs
                    phi = squeeze(app.Data.phData(:,:,:,vib,Dir,:));
                    phi_unwrapped = phi + 2*pi .* double(unwrap_4D(phi));
                                        
                    % perform 3d smoothing of the phase over each wave
                    % phase
                    for ph = 1:app.nPhases
                        % create complex signal, smooth and extract the phase
                        tempPhase = exp(1i*phi_unwrapped(:,:,:,ph));
                        
                        % default smoothing parameters from MDEV script
                        parameters.filter = 'gaussian';
                        parameters.size = [5 5 1]; %[pixel]
                        parameters.sd = 0.65; %[pixel] standard deviation
                        if app.nSlices > 1
                            temp = smooth3(tempPhase, parameters.filter, parameters.size, parameters.sd);
                        else % nSlice == 1
                            tempPhase = repmat(tempPhase,[1 1 2]);
                            temp = smooth3(tempPhase, parameters.filter, parameters.size, parameters.sd);
                            temp = temp(:,:,1);
                        end
                        phi_unwrapped(:,:,:,ph) = angle(temp);
                    end
                    app.Data.phData(:,:,:,vib,Dir,:) = permute(phi_unwrapped, [1 2 3 5 6 4]);
                    count = count+1;
                    
                    waitbar (count/(app.nVibs*app.nDirs), h)
                end
            end
            close(h);
                       
            updateFigureAxes(app);
            updateTimeAxes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MREViewerUIFigure = uifigure('Visible', 'off');
            app.MREViewerUIFigure.Name = 'MRE Viewer';
            app.MREViewerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MREViewerUIFigureWindowButtonDown, true);
            app.LoadPARRECDataButton = uibutton(app.MREViewerUIFigure, 'push');
            app.LoadPARRECDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPARRECDataButtonPushed, true);
            app.LoadPARRECDataButton.Text = 'Load PARREC Data';
            app.magnitudeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.magnitudeAxes, 'Magnitude Image')
            xlabel(app.magnitudeAxes, '')
            ylabel(app.magnitudeAxes, '')
            app.magnitudeAxes.XTick = [];
            app.magnitudeAxes.YTick = [];
            app.phaseAxes = uiaxes(app.MREViewerUIFigure);
            title(app.phaseAxes, 'Phase Image')
            xlabel(app.phaseAxes, '')
            ylabel(app.phaseAxes, '')
            app.phaseAxes.XTick = [];
            app.phaseAxes.YTick = [];
            app.infoTable = uitable(app.MREViewerUIFigure);
            app.infoTable.ColumnName = {'Slices'; 'Directions'; 'Vibrations'; 'Phases'};
            app.infoTable.RowName = {};
            app.timeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.timeAxes, 'Time steps')
            xlabel(app.timeAxes, 'phase number')
            ylabel(app.timeAxes, '')
            app.UnwrapandSmoothPhaseButton = uibutton(app.MREViewerUIFigure, 'push');
            app.UnwrapandSmoothPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @UnwrapandSmoothPhaseButtonPushed, true);
            app.UnwrapandSmoothPhaseButton.Text = 'Unwrap and Smooth Phase';
            app.SliceSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.MREViewerUIFigure);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.SliceSpinner.Value = 1;
            app.DirectionSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.DirectionSpinnerLabel.Text = 'Direction';
            app.DirectionSpinner = uispinner(app.MREViewerUIFigure);
            app.DirectionSpinner.ValueChangingFcn = createCallbackFcn(app, @DirectionSpinnerValueChanging, true);
            app.DirectionSpinner.Value = 1;
            app.VibrationSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.VibrationSpinnerLabel.Text = {'Vibration'; ''};
            app.VibrationSpinner = uispinner(app.MREViewerUIFigure);
            app.VibrationSpinner.ValueChangingFcn = createCallbackFcn(app, @VibrationSpinnerValueChanging, true);
            app.VibrationSpinner.Value = 1;
            app.PhaseSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.PhaseSpinnerLabel.Text = {'Phase'; ''};
            app.PhaseSpinner = uispinner(app.MREViewerUIFigure);
            app.PhaseSpinner.ValueChangingFcn = createCallbackFcn(app, @PhaseSpinnerValueChanging, true);
            app.PhaseSpinner.Value = 1;
            app.MREScanInfoLabel = uilabel(app.MREViewerUIFigure);
            app.MREScanInfoLabel.Text = 'MRE Scan Info ';
            app.filenameLabel = uilabel(app.MREViewerUIFigure);
            app.filenameLabel.Text = 'filename:';
            app.filenameEditField = uieditfield(app.MREViewerUIFigure, 'text');
            app.filenameEditField.Editable = 'off';
            % Show the figure after all components are created
            app.MREViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewMRE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MREViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MREViewerUIFigure)
        end
    end
end